classdef DeletingAProject < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        DeleteaProjectUIFigure       matlab.ui.Figure
        SelectaprojectDropDownLabel  matlab.ui.control.Label
        SelectaprojectDropDown       matlab.ui.control.DropDown
        DoneButton                   matlab.ui.control.Button
        SelectaprojecttodeleteLabel  matlab.ui.control.Label
    end

    
    properties (Access = public)
        txt3,txt,txt_for_recommend 
        txt_for_recommend_2% Description
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            app.txt3= getappdata(0,"txt3");
            app.txt_for_recommend =getappdata(0,"txt_for_recommend");
            app.txt_for_recommend_2 = ['Select a project';(app.txt_for_recommend(1,2:end))'];
            app.txt = getappdata(0,"txt");
            app.SelectaprojectDropDown.Items = app.txt_for_recommend_2;
        end

        % Value changed function: SelectaprojectDropDown
        function SelectaprojectDropDownValueChanged(app, event)
            
            value = app.SelectaprojectDropDown.Value;
            
            if strcmpi(value,'Select a project') == 0
                pause(0.5)
                msg = 'Are you sure you want to delete?';
                title = 'confirm delete';
                waitfor(uiconfirm(app.DeleteaProjectUIFigure,msg,title,'Options',{'Yes','No'},'DefaultOption',1,"CancelOption",2,...
                    'Icon','warning'));
                
                value_code = app.txt3(find(strcmpi(value,app.txt3(:,1))),2);
                app.txt3(find(strcmpi(value,app.txt3(:,1))),:) = [];
                
                
                %%% for txt  %%%%%%%%%%%%%%%%%%

                
                for m = 1: size(app.txt,1)
                    
                    extracted_txt = app.txt(m,:);
                    extracted_txt_for_recommend = app.txt_for_recommend(m,2:end);
                    
                    extracted_txt(find(contains(extracted_txt,value_code))) = [];
                    extracted_txt_for_recommend(find(contains(extracted_txt_for_recommend,value))) = [];
                   
                    new_sorted_txt(m,:) = extracted_txt;
                    new_txt_for_recommend(m,:) = extracted_txt_for_recommend;
                    clear extracted_txt
                    clear extracted_txt_for_recommend
                end
                app.txt = new_sorted_txt;
                app.txt_for_recommend = [app.txt_for_recommend(:,1) new_txt_for_recommend];
                clear new_sorted_txt
                clear new_txt_for_recommend
               %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%% 
               msg = sprintf(' %s \n Project Deleted Successfully',value);
               %title = 'Success';
              uialert(app.DeleteaProjectUIFigure,msg,'Success','Icon','success');
              app.txt_for_recommend_2 = ['Select a project';(app.txt_for_recommend(1,2:end))'];
              app.SelectaprojectDropDown.Items = app.txt_for_recommend_2;
              
              setappdata(0,"txt3",app.txt3)
              setappdata(0,"txt",app.txt);
              setappdata(0,"txt_for_recommend",app.txt_for_recommend);
            end
        end

        % Button pushed function: DoneButton
        function DoneButtonPushed(app, event)
            closereq
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.DeleteaProjectUIFigure = uifigure('Visible', 'off');
            app.DeleteaProjectUIFigure.Name = 'Delete a Project';
            app.SelectaprojectDropDownLabel = uilabel(app.DeleteaProjectUIFigure);
            app.SelectaprojectDropDownLabel.Text = 'Select a project';
            app.SelectaprojectDropDown = uidropdown(app.DeleteaProjectUIFigure);
            app.SelectaprojectDropDown.Items = {};
            app.SelectaprojectDropDown.ValueChangedFcn = createCallbackFcn(app, @SelectaprojectDropDownValueChanged, true);
            app.SelectaprojectDropDown.Value = {};
            app.DoneButton = uibutton(app.DeleteaProjectUIFigure, 'push');
            app.DoneButton.ButtonPushedFcn = createCallbackFcn(app, @DoneButtonPushed, true);
            app.DoneButton.Text = 'Done';
            app.SelectaprojecttodeleteLabel = uilabel(app.DeleteaProjectUIFigure);
            app.SelectaprojecttodeleteLabel.Text = 'Select a project to delete';
            % Show the figure after all components are created
            app.DeleteaProjectUIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = DeletingAProject

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.DeleteaProjectUIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.DeleteaProjectUIFigure)
        end
    end
end