classdef Main < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        ProjectAllocationUIFigure  matlab.ui.Figure
        Image                      matlab.ui.control.Image
        HelpButton                 matlab.ui.control.Button
        BrowseButton               matlab.ui.control.Button
        BrowseButton_2             matlab.ui.control.Button
        BrowseButton_3             matlab.ui.control.Button
        TextArea_2                 matlab.ui.control.TextArea
        LoadButton                 matlab.ui.control.Button
        TextArea_3                 matlab.ui.control.TextArea
        TextArea_4                 matlab.ui.control.TextArea
        ProjectAllocationLabel     matlab.ui.control.Label
        DeleteaProjectButton       matlab.ui.control.Button
        DeleteaStudentButton       matlab.ui.control.Button
        MpPCButton                 matlab.ui.control.Button
        RunButton                  matlab.ui.control.Button
        Lamp                       matlab.ui.control.Lamp
        CreditsButton              matlab.ui.control.Button
    end

    
    properties (Access = public)
        num,txt, num_sort,txt_sort,nu,tx  % Description  % From Excel files
        
        txt3,N_students,roll_and_CGPA,txt_for_recommend % from assigning_project_codes function file
        Number_of_projs_floated_coded,Max_proj_allocations % from number_of_projects_floated function file.
        
        Projnames_GUI,Roll_nos_GUI,original_proj_list,original_sorted_CGPA,sorted_CGPA,sorted_txt,sorted_Roll_nos,total_choices
        
        file1,filepath1,file2,filepath2,file3,filepath3
    end


    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: BrowseButton
        function BrowseButtonPushed(app, event)
            [app.file1,app.filepath1] = uigetfile({'*.*','Select the Excel file (.xlsx)'},...
                'Please upload the excel file which contains student choices');
            figure(app.ProjectAllocationUIFigure)           
            app.TextArea_2.Value = app.file1;
            %assignin("base","num",num)
            % code
        end

        % Button pushed function: BrowseButton_2
        function BrowseButton_2Pushed(app, event)
            [app.file2,app.filepath2] = uigetfile({'*.*','Select the Excel file (.xlsx)'},...
                'please upload the excel file that contains Student CGPA Information ' );
            figure(app.ProjectAllocationUIFigure)
            app.TextArea_3.Value = app.file2;
            
        end

        % Button pushed function: BrowseButton_3
        function BrowseButton_3Pushed(app, event)
            [app.file3,app.filepath3] = uigetfile({'*.*','Select the Excel file (.xlsx)'},...
                'please upload the excel file that contains Gate Score Information');
            figure(app.ProjectAllocationUIFigure)
            app.TextArea_4.Value = app.file3;
        end

        % Callback function
        function BrowseButton_4Pushed(app, event)
            
        end

        % Button pushed function: LoadButton
        function LoadButtonPushed(app, event)
            
            filename1 = fullfile(app.filepath1, app.file1);
            [app.num,app.txt] = xlsread(filename1);
            
            filename2 = fullfile(app.filepath2, app.file2);
            [app.num_sort,app.txt_sort] = xlsread(filename2);
            
            filename3 = fullfile(app.filepath3, app.file3);
            [app.nu,app.tx] = xlsread(filename3);
            
            setappdata(0,'nu',app.nu);


            [Roll_nos_choices,app.txt3,app.N_students,app.txt,app.txt_for_recommend] = assigning_project_codes(app.num,app.txt);  % Calling the function
            
            
            setappdata(0,"txt_for_recommend",app.txt_for_recommend);
            
            
            %[num_sort,txt_sort] = xlsread('name_CGPA_2_orig');
            txt_headers = string(app.txt_sort(1,:));   % First row contains headings
            
            button = 'NAME';
            button_2 = 'CGPA';
            button_3 = 'roll no';
            
            for i = 1: length(txt_headers)
                if strcmpi(txt_headers(i),button)
                    disp(['The student names are in the column ', num2str(i),'']);
                    name_column = i;
                elseif strcmpi(txt_headers(i),button_2)
                    disp(['The CGPA column is ', num2str(i),'']);
                    CGPA_column = i;
                    
                elseif strcmpi(txt_headers(i),button_3)
                    disp(['The Student Roll no column is ', num2str(i),'']);
                    Roll_no_column = i;
                end
            end


            Roll_nos_CGPA = app.num_sort(:,Roll_no_column);  % Roll_nos_CGPA mean the roll nos are taken from CGPA file.
            
            %%% Condition to check whether all the Roll nos are unique in
            %%% the CGPA file
            if length(Roll_nos_CGPA) == length(unique(Roll_nos_CGPA))
                disp('All the Roll nos are unique in the CGPA file')
            else
                disp('Duplicate Roll no entry in the CGPA file')
                error('Duplicate Roll no entry in the CGPA file,exiting')
            end
            %%%
            
            %%% Condition to check whether all the Roll nos are unique in
            %%% the CGPA file
            if length(Roll_nos_choices) == length(unique(Roll_nos_choices))
                disp('All the Roll nos are unique in the Student Choices file')
            else
                disp('Duplicate Roll no entry in the Student choices file')
                error('Duplicate Roll no entry in the Student Choices file,exiting')
            end
            %%%
            
            CGPA = app.num_sort(:,CGPA_column);
            
            app.roll_and_CGPA = [Roll_nos_CGPA CGPA];
            
            for i = 1: length(Roll_nos_choices)  %
                
                result = sum(Roll_nos_choices(i) == Roll_nos_CGPA); % checking for unmatches and duplicates
                
                if result == 1
                    
                    sprintf('Roll no %.f ,matched in both files',Roll_nos_choices(i));
                elseif result == 2
                    Error_Prone = sprintf('Duplicate Roll number entry for the roll number %.f, either in the student choices excel file or the CGPA excel file',Roll_nos_choices(i))
                    error('Error, exiting')
                else
                    Error_Prone = sprintf('Info about Roll no %.f or %.f are missing in either one of the file (Student choices or CGPA excel file)',Roll_nos_choices(i),Roll_nos_CGPA(i))
                    error('Error, exiting');
                end
                clear result;
            end
            app.Lamp.Visible='on';
            
            app.Lamp.Color='g';
            
            app.RunButton.Enable = 'on';
            app.MpPCButton.Tooltip = 'Specifying this will ensure the allocation of the projects based on the Maximum Number of Projects per Professor';
            app.DeleteaProjectButton.Tooltip = ...
                'If a Professor is no longer interested in taking up a certain project';
            app.DeleteaStudentButton.Tooltip = ...
                'If a Student lacks eligibility. Note that deleting a student also removes the choices he made';
            app.DeleteaProjectButton.Enable='on';
            app.MpPCButton.Enable = 'on';
            app.DeleteaStudentButton.Enable = 'on';
            app.RunButton.Tooltip = 'Start the Allocation';
            
        end

        % Button pushed function: HelpButton
        function HelpButtonPushed(app, event)
            winopen("manual.pdf")
        end

        % Value changed function: TextArea_2
        function TextArea_2ValueChanged(app, event)
            value = app.TextArea_2.Value;
            
        end

        % Value changed function: TextArea_4
        function TextArea_4ValueChanged(app, event)
            value = app.TextArea_4.Value;
            
        end

        % Button pushed function: RunButton
        function RunButtonPushed(app, event)
            app.RunButton.Icon = 'circles-menu-7_200_transparent.gif';
            app.RunButton.Enable = 'off';
            %app.MpPCButton.Enable = 'off';
            app.DeleteaProjectButton.Enable = 'off';
            app.DeleteaStudentButton.Enable = 'off';
            
            if app.MpPCButton.Enable == 'on'
                app.MpPCButton.Enable = 'off';
                answer = questdlg('\fontsize{14}You are one step from actual allotment. Do you want to reconsider specifying maximum number of projects per professor',...
                    'Choices',...
                    'Yes','No',struct('Default','','Interpreter','tex'));
                
                switch answer
                    
                    case 'Yes'
                        
                        
                        setappdata(0,"txt3",app.txt3);
                        setappdata(0,"N_students",app.N_students);
                        
                        
                        calling_MpPC_app = MpPC_table;
                        waitfor(calling_MpPC_app);


                       app.Number_of_projs_floated_coded = getappdata(0,'Number_of_projs_floated_coded');
                       app.Max_proj_allocations = getappdata(0,'Max_proj_allocations');
                        
                       app.original_proj_list = app.txt3;
                        
                        
                        % assignin("base","txt_for_recommend",app.txt_for_recommend)
                        
                        setappdata(0,"Max_proj_checker",[app.Number_of_projs_floated_coded(:,1) app.Max_proj_allocations]);
                        setappdata(0,"txt3",app.txt3)
                        
                        callingapp = x_gui;
                        waitfor(callingapp);
                        
                        %  while strcmpi(Roll_nos_GUI,'kfjhsedh') == 1
                        %      pause(1)
                        % end
                        
                         app.Roll_nos_GUI = getappdata(0,"Roll_nos_GUI");
                         app.Projnames_GUI = getappdata(0,"Projnames_GUI");
%                         app.Roll_nos_GUI = evalin("base","Roll_nos_GUI");
%                         app.Projnames_GUI = evalin("base","Projnames_GUI");
                        
                        
                        [app.N_students,app.txt,app.txt3,app.roll_and_CGPA,app.Roll_nos_GUI,app.Projnames_GUI] = ...
                            recommendations_fn(app.Roll_nos_GUI,app.Projnames_GUI,app.txt3,...
                            app.txt,app.N_students,app.roll_and_CGPA);
                        
                        
                        %% Calling sorting_CGPA function and extracting variables
                        [sorted_details,app.sorted_txt] = Sorting_CGPA(app.roll_and_CGPA,app.txt);  % Calling sorting CGPA function file.
                        
                        app.sorted_Roll_nos = sorted_details(:,2);
                        app.sorted_CGPA = sorted_details(:,3:4);
                        %% Calling some handy functions


                        %% Actual allocation is done here
                        
                        [app.total_choices,~] = size(app.original_proj_list);
                        [app.N_students,~] = size(app.sorted_txt);
                        
                        
                        app.original_sorted_CGPA = app.sorted_CGPA;
                        
                        mtp(app.Number_of_projs_floated_coded,...
                            app.Max_proj_allocations,app.Projnames_GUI,app.Roll_nos_GUI, ...
                            app.original_proj_list,app.original_sorted_CGPA,...
                            app.sorted_CGPA,app.sorted_txt,app.sorted_Roll_nos,app.txt3,app.N_students,app.total_choices);


                    case 'No'
                        
                        [~,app.Number_of_projs_floated_coded] = number_of_projs_floated(app.txt3,app.N_students);
                        
                        app.Max_proj_allocations = app.Number_of_projs_floated_coded(:,2);
                        app.original_proj_list = app.txt3;
                        
                        setappdata(0,"Max_proj_checker",[app.Number_of_projs_floated_coded(:,1) app.Max_proj_allocations]);
                        setappdata(0,"txt3",app.txt3)
 % assignin("base","txt_for_recommend",app.txt_for_recommend)
                        
                        callingapp = x_gui;
                        waitfor(callingapp);
                        
                        
                        %  while strcmpi(Roll_nos_GUI,'kfjhsedh') == 1
                        %      pause(1)
                        % end
                        
                        app.Roll_nos_GUI = getappdata(0,"Roll_nos_GUI");
                        app.Projnames_GUI = getappdata(0,"Projnames_GUI");
%                        app.Roll_nos_GUI = evalin("base","Roll_nos_GUI");
%                         app.Projnames_GUI = evalin("base","Projnames_GUI");
%                         
                        
                        [app.N_students,app.txt,app.txt3,app.roll_and_CGPA,app.Roll_nos_GUI,app.Projnames_GUI] = ...
                            recommendations_fn(app.Roll_nos_GUI,app.Projnames_GUI,app.txt3,...
                            app.txt,app.N_students,app.roll_and_CGPA);
                        
                        
                        %% Calling sorting_CGPA function and extracting variables
                        [sorted_details,app.sorted_txt] = Sorting_CGPA(app.roll_and_CGPA,app.txt);  % Calling sorting CGPA function file.
                        
                        app.sorted_Roll_nos = sorted_details(:,2);
                        app.sorted_CGPA = sorted_details(:,3:4);
                        %% Calling some handy functions


                        %% Actual allocation is done here
                        
                        [app.total_choices,~] = size(app.original_proj_list);
                        [app.N_students,~] = size(app.sorted_txt);
                        
                        
                        app.original_sorted_CGPA = app.sorted_CGPA;
                        
                        mtp(app.Number_of_projs_floated_coded,...
                            app.Max_proj_allocations,app.Projnames_GUI,app.Roll_nos_GUI, ...
                            app.original_proj_list,app.original_sorted_CGPA,...
                            app.sorted_CGPA,app.sorted_txt,app.sorted_Roll_nos,app.txt3,app.N_students,app.total_choices);

                        
                end
                
            else
                
                %[~,app.Number_of_projs_floated_coded] = number_of_projs_floated(app.txt3,app.N_students);
                
                %app.Max_proj_allocations = app.Number_of_projs_floated_coded(:,2);
                
                        app.original_proj_list = app.txt3;
                        
                        setappdata(0,"Max_proj_checker",[app.Number_of_projs_floated_coded(:,1) app.Max_proj_allocations]);
                        setappdata(0,"txt3",app.txt3)
                        % assignin("base","txt_for_recommend",app.txt_for_recommend)
                        
                        callingapp = x_gui;
                        waitfor(callingapp);
                        
                        %  while strcmpi(Roll_nos_GUI,'kfjhsedh') == 1
                        %      pause(1)
                        % end
                        
                        app.Roll_nos_GUI = getappdata(0,"Roll_nos_GUI");
                        app.Projnames_GUI = getappdata(0,"Projnames_GUI");
%                        app.Roll_nos_GUI = evalin("base","Roll_nos_GUI");
%                        app.Projnames_GUI = evalin("base","Projnames_GUI");
                        
                        
                        [app.N_students,app.txt,app.txt3,app.roll_and_CGPA,app.Roll_nos_GUI,app.Projnames_GUI] = ...
                            recommendations_fn(app.Roll_nos_GUI,app.Projnames_GUI,app.txt3,...
                            app.txt,app.N_students,app.roll_and_CGPA);
                        
                        
                        %% Calling sorting_CGPA function and extracting variables
                        [sorted_details,app.sorted_txt] = Sorting_CGPA(app.roll_and_CGPA,app.txt);  % Calling sorting CGPA function file.
                        
                        app.sorted_Roll_nos = sorted_details(:,2);
                        app.sorted_CGPA = sorted_details(:,3:4);
                        %% Calling some handy functions


                        %% Actual allocation is done here
                        
                        [app.total_choices,~] = size(app.original_proj_list);
                        [app.N_students,~] = size(app.sorted_txt);
                        
                        
                        app.original_sorted_CGPA = app.sorted_CGPA;
                        
                        mtp(app.Number_of_projs_floated_coded,...
                            app.Max_proj_allocations,app.Projnames_GUI,app.Roll_nos_GUI, ...
                            app.original_proj_list,app.original_sorted_CGPA,...
                            app.sorted_CGPA,app.sorted_txt,app.sorted_Roll_nos,app.txt3,app.N_students,app.total_choices);
                        
                
            end
            
            
            app.RunButton.Icon='';
            app.Lamp.Color = 'red';
            app.RunButton.Tooltip = 'Load the files';
            %app.RunButton.Enable = 'on';
            app.RunButton.Text='Run Again';
            app.MpPCButton.Enable = 'off';
            app.MpPCButton.Tooltip = 'Load the files';
            app.DeleteaProjectButton.Enable = 'off';
            app.DeleteaProjectButton.Tooltip = 'Load the files';
            app.DeleteaStudentButton.Enable = 'off';
            app.DeleteaStudentButton.Tooltip = 'Load the files';
            clearAllMemoizedCaches
            clear all
            clearvars
            
        end

        % Button pushed function: MpPCButton
        function MpPCButtonPushed(app, event)
            
            setappdata(0,"txt3",app.txt3);
            setappdata(0,"N_students",app.N_students);
            
            calling_MpPC_app = MpPC_table;
            waitfor(calling_MpPC_app);


         app.Number_of_projs_floated_coded = getappdata(0,'Number_of_projs_floated_coded');
            app.Max_proj_allocations = getappdata(0,'Max_proj_allocations');
            
            
            setappdata(0,"Max_proj_checker",[app.Number_of_projs_floated_coded(:,1) app.Max_proj_allocations]);

            
            app.MpPCButton.Enable='off';
            % assignin("base","txt_for_recommend",app.txt_for_recommend)
            
            app.DeleteaProjectButton.Enable = 'off';
            app.DeleteaProjectButton.Tooltip = 'You cannot delete a project after assigning Maximum Number of Projects per Professor.';
        end

        % Button pushed function: DeleteaProjectButton
        function DeleteaProjectButtonPushed(app, event)
            setappdata(0,"txt",app.txt)
            setappdata(0,"txt3",app.txt3);
            callingdeleteprojapp = DeletingAProject;
            waitfor(callingdeleteprojapp);
            app.txt3 = getappdata(0,"txt3");
            app.txt =  getappdata(0,"txt");
            app.txt_for_recommend = getappdata(0,"txt_for_recommend");
        end

        % Button pushed function: CreditsButton
        function CreditsButtonPushed(app, event)
            callingcreditsapp = credits_windows;
            waitfor(callingcreditsapp);
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.ProjectAllocationUIFigure = uifigure('Visible', 'off');
            app.ProjectAllocationUIFigure.Name = 'Project Allocation';
            app.Image = uiimage(app.ProjectAllocationUIFigure);
            app.Image.ImageSource = 'iitg.png';
            app.HelpButton = uibutton(app.ProjectAllocationUIFigure, 'push');
            app.HelpButton.ButtonPushedFcn = createCallbackFcn(app, @HelpButtonPushed, true);
            app.HelpButton.Icon = '36_bulb_outline.gif';
            app.HelpButton.Text = 'Help';
            app.BrowseButton = uibutton(app.ProjectAllocationUIFigure, 'push');
            app.BrowseButton.ButtonPushedFcn = createCallbackFcn(app, @BrowseButtonPushed, true);
            app.BrowseButton.Icon = 'folder-with-file_200_transparent.gif';
            app.BrowseButton.Text = 'Browse';
            app.BrowseButton_2 = uibutton(app.ProjectAllocationUIFigure, 'push');
            app.BrowseButton_2.ButtonPushedFcn = createCallbackFcn(app, @BrowseButton_2Pushed, true);
            app.BrowseButton_2.Icon = 'folder-with-file_200_transparent.gif';
            app.BrowseButton_2.Text = 'Browse';
            app.BrowseButton_3 = uibutton(app.ProjectAllocationUIFigure, 'push');
            app.BrowseButton_3.ButtonPushedFcn = createCallbackFcn(app, @BrowseButton_3Pushed, true);
            app.BrowseButton_3.Icon = 'folder-with-file_200_transparent.gif';
            app.BrowseButton_3.Text = 'Browse';
            app.TextArea_2 = uitextarea(app.ProjectAllocationUIFigure);
            app.TextArea_2.ValueChangedFcn = createCallbackFcn(app, @TextArea_2ValueChanged, true);
            app.TextArea_2.Editable = 'off';
            app.TextArea_2.Value = {'Upload the excel file containing student choices'};
            app.LoadButton = uibutton(app.ProjectAllocationUIFigure, 'push');
            app.LoadButton.ButtonPushedFcn = createCallbackFcn(app, @LoadButtonPushed, true);
            app.LoadButton.Text = 'Load';
            app.TextArea_3 = uitextarea(app.ProjectAllocationUIFigure);
            app.TextArea_3.Value = {'Upload the excel file containing CGPA details'};
            app.TextArea_4 = uitextarea(app.ProjectAllocationUIFigure);
            app.TextArea_4.ValueChangedFcn = createCallbackFcn(app, @TextArea_4ValueChanged, true);
            app.TextArea_4.Value = {'Upload the excel file containing GATE score details'};
            app.ProjectAllocationLabel = uilabel(app.ProjectAllocationUIFigure);
            app.ProjectAllocationLabel.Text = ' Project Allocation';
            app.DeleteaProjectButton = uibutton(app.ProjectAllocationUIFigure, 'push');
            app.DeleteaProjectButton.ButtonPushedFcn = createCallbackFcn(app, @DeleteaProjectButtonPushed, true);
            app.DeleteaProjectButton.Tooltip = {'If a Professor is no longer interested in taking up a certain project'};
            app.DeleteaProjectButton.Text = 'Delete a Project';
            app.DeleteaStudentButton = uibutton(app.ProjectAllocationUIFigure, 'push');
            app.DeleteaStudentButton.Tooltip = {'If a Student lacks eligibility. Note that deleting a student also removes the choices he made'};
            app.DeleteaStudentButton.Text = 'Delete a Student';
            app.MpPCButton = uibutton(app.ProjectAllocationUIFigure, 'push');
            app.MpPCButton.ButtonPushedFcn = createCallbackFcn(app, @MpPCButtonPushed, true);
            app.MpPCButton.Icon = '425_exclamation_mark_bubble_outline.gif';
            app.MpPCButton.Tooltip = {'Specifying this will ensure the allocation of the projects based on the Maximum Number of Projects per Professor'; ''};
            app.MpPCButton.Text = 'Do You Want To Specify Maximum Number of Projects Per Professor?';
            app.RunButton = uibutton(app.ProjectAllocationUIFigure, 'push');
            app.RunButton.ButtonPushedFcn = createCallbackFcn(app, @RunButtonPushed, true);
            app.RunButton.Enable = 'off';
            app.RunButton.Text = 'Run';
            app.Lamp = uilamp(app.ProjectAllocationUIFigure);
            app.Lamp.Visible = 'off';
            app.CreditsButton = uibutton(app.ProjectAllocationUIFigure, 'push');
            app.CreditsButton.ButtonPushedFcn = createCallbackFcn(app, @CreditsButtonPushed, true);
            app.CreditsButton.Text = 'Credits';
            % Show the figure after all components are created
            app.ProjectAllocationUIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = Main

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.ProjectAllocationUIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.ProjectAllocationUIFigure)
        end
    end
end