classdef MpPC_table < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                    matlab.ui.Figure
        UITable                     matlab.ui.control.Table
        LoadButton                  matlab.ui.control.Button
        TotalNoofProjectsasperUserassignmentEditFieldLabel  matlab.ui.control.Label
        TotalNoofProjectsasperUserassignmentEditField  matlab.ui.control.NumericEditField
        TotalNoofstudentsEditFieldLabel  matlab.ui.control.Label
        TotalNoofstudentsEditField  matlab.ui.control.NumericEditField
        TotalNoofFloatedProjectsEditFieldLabel  matlab.ui.control.Label
        TotalNoofFloatedProjectsEditField  matlab.ui.control.NumericEditField
        DoneButton                  matlab.ui.control.Button
        EditableColumnLabel         matlab.ui.control.Label
    end

    
    properties (Access = public)
        a1
        a2
        a3 % Description
        N_students,Number_of_projs_floated_coded
        txt3;
         % Description
    end
    
    methods (Access = public)
        
        function updatesum(app)
            
            t = app.UITable.DisplayData;
            x2 = t.a3;
            app.TotalNoofProjectsasperUserassignmentEditField.Value = sum(str2double(x2));
            
        end
        

    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            %uiwait(gui_table)
            app.txt3 = getappdata(0,"txt3");
            app.N_students = getappdata(0,"N_students");
             [app.N_students,app.Number_of_projs_floated_coded] = number_of_projs_floated(app.txt3,app.N_students);

        end

        % Cell edit callback: UITable
        function UITableCellEdit(app, event)
            indices = event.Indices;
            newData = event.NewData;
            %prof_names = eval()
            % app.TotalNoofProjectsasperUserassignmentEditField.Value =  sum(str2double(newData));
        end

        % Cell selection callback: UITable
        function UITableCellSelection(app, event)
            indices = event.Indices;
            
        end

        % Display data changed function: UITable
        function UITableDisplayDataChanged(app, event)
            updatesum(app);
            
            
        end

        % Button pushed function: LoadButton
        function LoadButtonPushed(app, event)
          %  N_students = evalin("base","N_students");
            app.LoadButton.Enable = 'off';
            %load projs_floated.mat N_students
            % number_of_projs_floated
            t = readtable("FinalAssignedStatus.xls");
            vars = {'a1','a2','a3'};
            t = t(1:end,vars);
            %a = table2cell(t);
            %t(:,3) = cellfun(@(x) ['<html><table border=0 width=400 bgcolor=#FF0000><TR><TD>' x '</TD></TR> </table></html>'], t(:,3), 'UniformOutput', false);
            % No_of_students = evalin("base","N_students");
            app.TotalNoofstudentsEditField.Value = app.N_students;
            app.TotalNoofstudentsEditField.Enable = 'off';
            
            total_floated_projs_count = sum(str2double(t.a2));
            app.TotalNoofFloatedProjectsEditField.Value = total_floated_projs_count;
            app.TotalNoofFloatedProjectsEditField.Enable = 'off';
            % Nc = string(ones(length(Number_of_projs_floated),1));
            
            %  Nc =["user";Number_of_projs_floated(:,2)];
            % t = [Number_of_projs_floated Nc(2:end)];
            app.UITable.Data = t;
            app.TotalNoofProjectsasperUserassignmentEditField.Value =sum(str2double(t.a3));
            
        end

        % Value changed function: 
        % TotalNoofProjectsasperUserassignmentEditField
        function TotalNoofProjectsasperUserassignmentEditFieldValueChanged(app, event)
           % value = app.TotalNoofProjectsasperUserassignmentEditField.Value;
           % value = sum(app.UITable.Data.age)
        end

        % Button pushed function: DoneButton
        function DoneButtonPushed(app, event)
           % assignin()
           % assignin("base",'Max_proj_allocations',app.UITable.Data.a3);
            setappdata(0,'Max_proj_allocations',app.UITable.Data.a3);
            setappdata(0,'Number_of_projs_floated_coded',app.Number_of_projs_floated_coded)
            
            if sum(str2double(app.UITable.Data.a3)) < app.TotalNoofstudentsEditField.Value
                closereq
                msg = 'No of projects is less than the number of students. Allotment not possible';
                errordlg(msg)
                error(msg)
                
            end
            closereq
           % uiresume(app)
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UITable = uitable(app.UIFigure);
            app.UITable.ColumnName = {'Professor Name'; 'Number of Projects floated'; 'Maximum No of Projects to be alloted (User Input)'};
            app.UITable.RowName = {};
            app.UITable.ColumnEditable = [false false true];
            app.UITable.CellEditCallback = createCallbackFcn(app, @UITableCellEdit, true);
            app.UITable.CellSelectionCallback = createCallbackFcn(app, @UITableCellSelection, true);
            app.UITable.DisplayDataChangedFcn = createCallbackFcn(app, @UITableDisplayDataChanged, true);
            app.LoadButton = uibutton(app.UIFigure, 'push');
            app.LoadButton.ButtonPushedFcn = createCallbackFcn(app, @LoadButtonPushed, true);
            app.LoadButton.Text = 'Load';
            app.TotalNoofProjectsasperUserassignmentEditFieldLabel = uilabel(app.UIFigure);
            app.TotalNoofProjectsasperUserassignmentEditFieldLabel.Text = 'Total No of Projects as per User assignment';
            app.TotalNoofProjectsasperUserassignmentEditField = uieditfield(app.UIFigure, 'numeric');
            app.TotalNoofProjectsasperUserassignmentEditField.ValueChangedFcn = createCallbackFcn(app, @TotalNoofProjectsasperUserassignmentEditFieldValueChanged, true);
            app.TotalNoofstudentsEditFieldLabel = uilabel(app.UIFigure);
            app.TotalNoofstudentsEditFieldLabel.Text = 'Total No of students';
            app.TotalNoofstudentsEditField = uieditfield(app.UIFigure, 'numeric');
            app.TotalNoofFloatedProjectsEditFieldLabel = uilabel(app.UIFigure);
            app.TotalNoofFloatedProjectsEditFieldLabel.Text = 'Total No of Floated Projects';
            app.TotalNoofFloatedProjectsEditField = uieditfield(app.UIFigure, 'numeric');
            app.DoneButton = uibutton(app.UIFigure, 'push');
            app.DoneButton.ButtonPushedFcn = createCallbackFcn(app, @DoneButtonPushed, true);
            app.DoneButton.Text = 'Done';
            app.EditableColumnLabel = uilabel(app.UIFigure);
            app.EditableColumnLabel.Text = 'Editable Column';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = MpPC_table

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end