classdef Recommendations < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                    matlab.ui.Figure
        LoadDataButton              matlab.ui.control.Button
        ParticularChoicesLabel      matlab.ui.control.Label
        StudentInfoDropDownLabel    matlab.ui.control.Label
        StudentInfoDropDown         matlab.ui.control.DropDown
        ProjectInfoDropDownLabel    matlab.ui.control.Label
        ProjectInfoDropDown         matlab.ui.control.DropDown
        DoneButton                  matlab.ui.control.Button
        StudentInfoDropDown_2Label  matlab.ui.control.Label
        StudentInfoDropDown_2       matlab.ui.control.DropDown
        ProjectInfoDropDown_2Label  matlab.ui.control.Label
        ProjectInfoDropDown_2       matlab.ui.control.DropDown
        StudentInfoDropDown_3Label  matlab.ui.control.Label
        StudentInfoDropDown_3       matlab.ui.control.DropDown
        ProjectInfoDropDown_3Label  matlab.ui.control.Label
        ProjectInfoDropDown_3       matlab.ui.control.DropDown
        StudentInfoDropDown_4Label  matlab.ui.control.Label
        StudentInfoDropDown_4       matlab.ui.control.DropDown
        ProjectInfoDropDown_4Label  matlab.ui.control.Label
        ProjectInfoDropDown_4       matlab.ui.control.DropDown
        StudentInfoDropDown_5Label  matlab.ui.control.Label
        StudentInfoDropDown_5       matlab.ui.control.DropDown
        ProjectInfoDropDown_5Label  matlab.ui.control.Label
        ProjectInfoDropDown_5       matlab.ui.control.DropDown
        Image                       matlab.ui.control.Image
        NoparticularchoicesButton   matlab.ui.control.Button
        Lamp                        matlab.ui.control.Lamp
        Lamp_2                      matlab.ui.control.Lamp
        Lamp_3                      matlab.ui.control.Lamp
        Lamp_4                      matlab.ui.control.Lamp
        Lamp_5                      matlab.ui.control.Lamp
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
         % uiwait(app.UIFigure)
        end

        % Button pushed function: LoadDataButton
        function LoadDataButtonPushed(app, event)
            v = evalin("base",'original_proj_list');  % To import a variable from our workspace
            v = ['Select';v(:,1)];
            roll_nos = xlsread("./name_CGPA_2.xlsx");
            roll_nos = ['Select';string(roll_nos(:,1))];
            app.StudentInfoDropDown.Items = roll_nos;
            app.ProjectInfoDropDown.Items = v(:,1);
            app.StudentInfoDropDown_2.Items = roll_nos;
            app.ProjectInfoDropDown_2.Items = v(:,1);
            app.StudentInfoDropDown_3.Items = roll_nos;
            app.ProjectInfoDropDown_3.Items = v(:,1);
            app.StudentInfoDropDown_4.Items = roll_nos;
            app.ProjectInfoDropDown_4.Items = v(:,1);
            app.StudentInfoDropDown_5.Items = roll_nos;
            app.ProjectInfoDropDown_5.Items = v(:,1);
            
        end

        % Button pushed function: DoneButton
        function DoneButtonPushed(app, event)
            roll_nos = {
                app.StudentInfoDropDown.Value,
                app.StudentInfoDropDown_2.Value,
                app.StudentInfoDropDown_3.Value,
                app.StudentInfoDropDown_4.Value,
                app.StudentInfoDropDown_5.Value
                };
            
            projnames_gui = {
                app.ProjectInfoDropDown.Value,
                app.ProjectInfoDropDown_2.Value,
                app.ProjectInfoDropDown_3.Value,
                app.ProjectInfoDropDown_4.Value,
                app.ProjectInfoDropDown_5.Value
                };
            
            assignin('base','Roll_nos_GUI',roll_nos) % Export a variable back to workspace
            assignin('base','Projnames_GUI',projnames_gui)
            %save guioutput
            closereq
            
        end

        % Image clicked function: Image
        function ImageClicked(app, event)
            
        end

        % Button pushed function: NoparticularchoicesButton
        function NoparticularchoicesButtonPushed(app, event)
            roll_nos(1:5,:) = {'Select'}
            projnames_gui(1:5,:) = {'Select'}
            assignin('base','Roll_nos_GUI',roll_nos) % Export a variable back to workspace
            assignin('base','Projnames_GUI',projnames_gui)
            closereq
        end

        % Value changed function: StudentInfoDropDown
        function StudentInfoDropDownValueChanged(app, event)
            value = app.StudentInfoDropDown.Value;
            
            if strcmpi(app.StudentInfoDropDown.Value,'select') == 0
                app.Lamp.Color = 'g';
            end
        end

        % Value changed function: StudentInfoDropDown_2
        function StudentInfoDropDown_2ValueChanged(app, event)
            value = app.StudentInfoDropDown_2.Value;
            if strcmpi(app.StudentInfoDropDown_2.Value,'select') == 0
                app.Lamp_2.Color = 'g';
            end
        end

        % Value changed function: StudentInfoDropDown_3
        function StudentInfoDropDown_3ValueChanged(app, event)
            value = app.StudentInfoDropDown_3.Value;
            if strcmpi(app.StudentInfoDropDown_3.Value,'select') == 0
                app.Lamp_3.Color = 'g';
            end
        end

        % Value changed function: StudentInfoDropDown_4
        function StudentInfoDropDown_4ValueChanged(app, event)
            value = app.StudentInfoDropDown_4.Value;
            if strcmpi(app.StudentInfoDropDown_4.Value,'select') == 0
                app.Lamp_4.Color = 'g';
            end
        end

        % Value changed function: StudentInfoDropDown_5
        function StudentInfoDropDown_5ValueChanged(app, event)
            value = app.StudentInfoDropDown_5.Value;
            if strcmpi(app.StudentInfoDropDown_5.Value,'select') == 0
                app.Lamp_5.Color = 'g';
            end
        end

        % Value changed function: ProjectInfoDropDown
        function ProjectInfoDropDownValueChanged(app, event)
            value = app.ProjectInfoDropDown.Value;
            if strcmpi(app.ProjectInfoDropDown.Value,'select') == 0
                app.Lamp.Color = 'g';
            end
        end

        % Value changed function: ProjectInfoDropDown_2
        function ProjectInfoDropDown_2ValueChanged(app, event)
            value = app.ProjectInfoDropDown_2.Value;
            
         if strcmpi(app.ProjectInfoDropDown_2.Value,'select') == 0
                app.Lamp_2.Color = 'g';
            end
        end

        % Value changed function: ProjectInfoDropDown_3
        function ProjectInfoDropDown_3ValueChanged(app, event)
            value = app.ProjectInfoDropDown_3.Value;
            if strcmpi(app.ProjectInfoDropDown_3.Value,'select') == 0
                app.Lamp_3.Color = 'g';
            end
        end

        % Value changed function: ProjectInfoDropDown_4
        function ProjectInfoDropDown_4ValueChanged(app, event)
            value = app.ProjectInfoDropDown_4.Value;
            if strcmpi(app.ProjectInfoDropDown_4.Value,'select') == 0
                app.Lamp_4.Color = 'g';
           end
        end

        % Value changed function: ProjectInfoDropDown_5
        function ProjectInfoDropDown_5ValueChanged(app, event)
            value = app.ProjectInfoDropDown_5.Value;
            if strcmpi(app.ProjectInfoDropDown_5.Value,'select') == 0
                app.Lamp_5.Color = 'g';
            end
        end

        % Close request function: UIFigure
        function UIFigureCloseRequest(app, event)
        %uiresume(app.UIFigure)
            delete(app)
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIFigure.CloseRequestFcn = createCallbackFcn(app, @UIFigureCloseRequest, true);
            app.LoadDataButton = uibutton(app.UIFigure, 'push');
            app.LoadDataButton.ButtonPushedFcn = createCallbackFcn(app, @LoadDataButtonPushed, true);
            app.LoadDataButton.Text = 'Load Data';
            app.ParticularChoicesLabel = uilabel(app.UIFigure);
            app.ParticularChoicesLabel.Text = 'Particular Choices';
            app.StudentInfoDropDownLabel = uilabel(app.UIFigure);
            app.StudentInfoDropDownLabel.Text = 'Student Info';
            app.StudentInfoDropDown = uidropdown(app.UIFigure);
            app.StudentInfoDropDown.Items = {'Roll number'};
            app.StudentInfoDropDown.ValueChangedFcn = createCallbackFcn(app, @StudentInfoDropDownValueChanged, true);
            app.StudentInfoDropDown.Value = 'Roll number';
            app.ProjectInfoDropDownLabel = uilabel(app.UIFigure);
            app.ProjectInfoDropDownLabel.Text = 'Project Info';
            app.ProjectInfoDropDown = uidropdown(app.UIFigure);
            app.ProjectInfoDropDown.Items = {'project name'};
            app.ProjectInfoDropDown.ValueChangedFcn = createCallbackFcn(app, @ProjectInfoDropDownValueChanged, true);
            app.ProjectInfoDropDown.Value = 'project name';
            app.DoneButton = uibutton(app.UIFigure, 'push');
            app.DoneButton.ButtonPushedFcn = createCallbackFcn(app, @DoneButtonPushed, true);
            app.DoneButton.Text = 'Done';
            app.StudentInfoDropDown_2Label = uilabel(app.UIFigure);
            app.StudentInfoDropDown_2Label.Text = 'Student Info';
            app.StudentInfoDropDown_2 = uidropdown(app.UIFigure);
            app.StudentInfoDropDown_2.Items = {'Roll number'};
            app.StudentInfoDropDown_2.ValueChangedFcn = createCallbackFcn(app, @StudentInfoDropDown_2ValueChanged, true);
            app.StudentInfoDropDown_2.Value = 'Roll number';
            app.ProjectInfoDropDown_2Label = uilabel(app.UIFigure);
            app.ProjectInfoDropDown_2Label.Text = 'Project Info';
            app.ProjectInfoDropDown_2 = uidropdown(app.UIFigure);
            app.ProjectInfoDropDown_2.Items = {'project name'};
            app.ProjectInfoDropDown_2.ValueChangedFcn = createCallbackFcn(app, @ProjectInfoDropDown_2ValueChanged, true);
            app.ProjectInfoDropDown_2.Value = 'project name';
            app.StudentInfoDropDown_3Label = uilabel(app.UIFigure);
            app.StudentInfoDropDown_3Label.Text = 'Student Info';
            app.StudentInfoDropDown_3 = uidropdown(app.UIFigure);
            app.StudentInfoDropDown_3.Items = {'Roll number'};
            app.StudentInfoDropDown_3.ValueChangedFcn = createCallbackFcn(app, @StudentInfoDropDown_3ValueChanged, true);
            app.StudentInfoDropDown_3.Value = 'Roll number';
            app.ProjectInfoDropDown_3Label = uilabel(app.UIFigure);
            app.ProjectInfoDropDown_3Label.Text = 'Project Info';
            app.ProjectInfoDropDown_3 = uidropdown(app.UIFigure);
            app.ProjectInfoDropDown_3.Items = {'project name'};
            app.ProjectInfoDropDown_3.ValueChangedFcn = createCallbackFcn(app, @ProjectInfoDropDown_3ValueChanged, true);
            app.ProjectInfoDropDown_3.Value = 'project name';
            app.StudentInfoDropDown_4Label = uilabel(app.UIFigure);
            app.StudentInfoDropDown_4Label.Text = 'Student Info';
            app.StudentInfoDropDown_4 = uidropdown(app.UIFigure);
            app.StudentInfoDropDown_4.Items = {'Roll number'};
            app.StudentInfoDropDown_4.ValueChangedFcn = createCallbackFcn(app, @StudentInfoDropDown_4ValueChanged, true);
            app.StudentInfoDropDown_4.Value = 'Roll number';
            app.ProjectInfoDropDown_4Label = uilabel(app.UIFigure);
            app.ProjectInfoDropDown_4Label.Text = 'Project Info';
            app.ProjectInfoDropDown_4 = uidropdown(app.UIFigure);
            app.ProjectInfoDropDown_4.Items = {'project name'};
            app.ProjectInfoDropDown_4.ValueChangedFcn = createCallbackFcn(app, @ProjectInfoDropDown_4ValueChanged, true);
            app.ProjectInfoDropDown_4.Value = 'project name';
            app.StudentInfoDropDown_5Label = uilabel(app.UIFigure);
            app.StudentInfoDropDown_5Label.Text = 'Student Info';
            app.StudentInfoDropDown_5 = uidropdown(app.UIFigure);
            app.StudentInfoDropDown_5.Items = {'Roll number'};
            app.StudentInfoDropDown_5.ValueChangedFcn = createCallbackFcn(app, @StudentInfoDropDown_5ValueChanged, true);
            app.StudentInfoDropDown_5.Value = 'Roll number';
            app.ProjectInfoDropDown_5Label = uilabel(app.UIFigure);
            app.ProjectInfoDropDown_5Label.Text = 'Project Info';
            app.ProjectInfoDropDown_5 = uidropdown(app.UIFigure);
            app.ProjectInfoDropDown_5.Items = {'project name'};
            app.ProjectInfoDropDown_5.ValueChangedFcn = createCallbackFcn(app, @ProjectInfoDropDown_5ValueChanged, true);
            app.ProjectInfoDropDown_5.Value = 'project name';
            app.Image = uiimage(app.UIFigure);
            app.Image.ImageClickedFcn = createCallbackFcn(app, @ImageClicked, true);
            app.Image.ImageSource = 'iitg.png';
            app.NoparticularchoicesButton = uibutton(app.UIFigure, 'push');
            app.NoparticularchoicesButton.ButtonPushedFcn = createCallbackFcn(app, @NoparticularchoicesButtonPushed, true);
            app.NoparticularchoicesButton.Text = 'No particular choices';
            app.Lamp = uilamp(app.UIFigure);
            app.Lamp_2 = uilamp(app.UIFigure);
            app.Lamp_3 = uilamp(app.UIFigure);
            app.Lamp_4 = uilamp(app.UIFigure);
            app.Lamp_5 = uilamp(app.UIFigure);
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = Recommendations

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end