classdef x_gui < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                     matlab.ui.Figure
        LoadDataButton               matlab.ui.control.Button
        ParticularChoicesLabel       matlab.ui.control.Label
        StudentInfoDropDownLabel     matlab.ui.control.Label
        StudentInfoDropDown          matlab.ui.control.DropDown
        ProjectInfoDropDownLabel     matlab.ui.control.Label
        ProjectInfoDropDown          matlab.ui.control.DropDown
        DoneButton                   matlab.ui.control.Button
        StudentInfoDropDown_2Label   matlab.ui.control.Label
        StudentInfoDropDown_2        matlab.ui.control.DropDown
        ProjectInfoDropDown_2Label   matlab.ui.control.Label
        ProjectInfoDropDown_2        matlab.ui.control.DropDown
        StudentInfoDropDown_3Label   matlab.ui.control.Label
        StudentInfoDropDown_3        matlab.ui.control.DropDown
        ProjectInfoDropDown_3Label   matlab.ui.control.Label
        ProjectInfoDropDown_3        matlab.ui.control.DropDown
        StudentInfoDropDown_4Label   matlab.ui.control.Label
        StudentInfoDropDown_4        matlab.ui.control.DropDown
        ProjectInfoDropDown_4Label   matlab.ui.control.Label
        ProjectInfoDropDown_4        matlab.ui.control.DropDown
        StudentInfoDropDown_5Label   matlab.ui.control.Label
        StudentInfoDropDown_5        matlab.ui.control.DropDown
        ProjectInfoDropDown_5Label   matlab.ui.control.Label
        ProjectInfoDropDown_5        matlab.ui.control.DropDown
        Image                        matlab.ui.control.Image
        NoparticularchoicesButton    matlab.ui.control.Button
        Lamp                         matlab.ui.control.Lamp
        Lamp_2                       matlab.ui.control.Lamp
        Lamp_3                       matlab.ui.control.Lamp
        Lamp_4                       matlab.ui.control.Lamp
        Lamp_5                       matlab.ui.control.Lamp
        Lamp_6                       matlab.ui.control.Lamp
        StudentInfoDropDown_6Label   matlab.ui.control.Label
        StudentInfoDropDown_6        matlab.ui.control.DropDown
        ProjectInfoDropDown_6Label   matlab.ui.control.Label
        ProjectInfoDropDown_6        matlab.ui.control.DropDown
        Lamp_7                       matlab.ui.control.Lamp
        StudentInfoDropDown_7Label   matlab.ui.control.Label
        StudentInfoDropDown_7        matlab.ui.control.DropDown
        ProjectInfoDropDown_7Label   matlab.ui.control.Label
        ProjectInfoDropDown_7        matlab.ui.control.DropDown
        Lamp_8                       matlab.ui.control.Lamp
        StudentInfoDropDown_8Label   matlab.ui.control.Label
        StudentInfoDropDown_8        matlab.ui.control.DropDown
        ProjectInfoDropDown_8Label   matlab.ui.control.Label
        ProjectInfoDropDown_8        matlab.ui.control.DropDown
        Lamp_9                       matlab.ui.control.Lamp
        StudentInfoDropDown_9Label   matlab.ui.control.Label
        StudentInfoDropDown_9        matlab.ui.control.DropDown
        ProjectInfoDropDown_9Label   matlab.ui.control.Label
        ProjectInfoDropDown_9        matlab.ui.control.DropDown
        Lamp_10                      matlab.ui.control.Lamp
        StudentInfoDropDown_10Label  matlab.ui.control.Label
        StudentInfoDropDown_10       matlab.ui.control.DropDown
        ProjectInfoDropDown_10Label  matlab.ui.control.Label
        ProjectInfoDropDown_10       matlab.ui.control.DropDown
    end

    
    properties (Access = public)
        
        % if app.LoadDataButtonPushed.event ~= 0
        %v = evalin("base",'txt_for_recommend');
        
        RN
        PR
        %  end
        
        % Description
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            % uiwait(app.UIFigure)
        end

        % Button pushed function: LoadDataButton
        function LoadDataButtonPushed(app, event)
            app.LoadDataButton.Icon='okk.gif';
            v = getappdata(0,"txt_for_recommend");
           % v = evalin("base",'txt_for_recommend');  % To import a variable from our workspace
            %v = ['Select';v(:,1)];

            app.RN = v(:,1);
            app.PR = v(:,2:end);
            item_RN = ['Select a roll number';app.RN];
            %initial_loading = repmat('select',1,length(roll_nos));
            % roll_nos = ['Select';roll_nos];
            % roll_nos = xlsread("./name_CGPA_2_orig.xlsx");
            % roll_nos = ['Select';string(roll_nos(:,1))];
            
            app.StudentInfoDropDown.Items = string(item_RN);
            app.ProjectInfoDropDown.Items = "Select a project";
            app.StudentInfoDropDown_2.Items = string(item_RN);
            app.ProjectInfoDropDown_2.Items = "Select a project";
            app.StudentInfoDropDown_3.Items = string(item_RN);
            app.ProjectInfoDropDown_3.Items = "Select a project";
            app.StudentInfoDropDown_4.Items = string(item_RN);
            app.ProjectInfoDropDown_4.Items = "Select a project";
            app.StudentInfoDropDown_5.Items = string(item_RN);
            app.ProjectInfoDropDown_5.Items = "Select a project";
            app.StudentInfoDropDown_6.Items = string(item_RN);
            app.ProjectInfoDropDown_6.Items = "Select a project";
            app.StudentInfoDropDown_7.Items = string(item_RN);
            app.ProjectInfoDropDown_7.Items = "Select a project";
            app.StudentInfoDropDown_8.Items = string(item_RN);
            app.ProjectInfoDropDown_8.Items = "Select a project";
            app.StudentInfoDropDown_9.Items = string(item_RN);
            app.ProjectInfoDropDown_9.Items = "Select a project";
            app.StudentInfoDropDown_10.Items = string(item_RN);
            app.ProjectInfoDropDown_10.Items = "Select a project";

        end

        % Button pushed function: DoneButton
        function DoneButtonPushed(app, event)
            roll_nos_final = {
                app.StudentInfoDropDown.Value,
                app.StudentInfoDropDown_2.Value,
                app.StudentInfoDropDown_3.Value,
                app.StudentInfoDropDown_4.Value,
                app.StudentInfoDropDown_5.Value,
                app.StudentInfoDropDown_6.Value,
                app.StudentInfoDropDown_7.Value,
                app.StudentInfoDropDown_8.Value,
                app.StudentInfoDropDown_9.Value,
                app.StudentInfoDropDown_10.Value
                };
            
            projnames_gui_final = {
                app.ProjectInfoDropDown.Value,
                app.ProjectInfoDropDown_2.Value,
                app.ProjectInfoDropDown_3.Value,
                app.ProjectInfoDropDown_4.Value,
                app.ProjectInfoDropDown_5.Value,
                app.ProjectInfoDropDown_6.Value,
                app.ProjectInfoDropDown_7.Value,
                app.ProjectInfoDropDown_8.Value,
                app.ProjectInfoDropDown_9.Value,
                app.ProjectInfoDropDown_10.Value
                };
            
            
            projnames_gui_final = (projnames_gui_final(cellfun(@(s)isempty(regexp(s,'Select a project')),projnames_gui_final)))';
            roll_nos_final = (roll_nos_final(cellfun(@(s)isempty(regexp(s,'Select a roll number')),roll_nos_final)))';
            
            actual_roll_nos = size(roll_nos_final,2);
            unique_roll_nos = size(unique(roll_nos_final),2);
            
            Max_proj_checker = getappdata(0,"Max_proj_checker");
            txt3 = getappdata(0,"txt3");
            
            for i = 1:length(projnames_gui_final)
                proj_code_index = find(strcmpi(projnames_gui_final(i),txt3(:,1)));
                proj_codes_RGUI(i) = txt3(proj_code_index,2);
                proj_codes_RGUI_code{i} = proj_codes_RGUI{i}(1:end-1)
            end
            
            unique_projnames_gui_final = unique(proj_codes_RGUI);
            
            %% CHECKING FOR CONDITIONS
            
            for i = 1: length(unique_projnames_gui_final)
                No_of_repetations = sum(strcmpi(unique_projnames_gui_final(i),proj_codes_RGUI));
                check_base_projname = find(strcmpi(proj_codes_RGUI_code(i),Max_proj_checker(:,1)));
                max_projs = Max_proj_checker(check_base_projname,2);
                
                if str2double(max_projs) < No_of_repetations
                    
                    
                    for i = 1:length(Max_proj_checker)
                        Max_proj_checker_before_editor{i,1} = Max_proj_checker{i}(1:end-4);
                    end
                    
                    Max_proj_checker_edited (:,1) = "Prof. " + Max_proj_checker_before_editor(:,1);
                    Max_proj_checker_edited (:,2) = Max_proj_checker(:,2);
                    uit = uitable('Parent',uifigure,'Position',[20 10 500 400],'Data', Max_proj_checker_edited);
                    %uit = uitable(app.UIFigure,'Position',[20 10 500 400],'Data', Max_proj_checker_edited);
                    set(uit,'columnname',{'Name of the Professor','Maximum Number of projects available to allot'})
                    
                    pause(1)
                    
                    Opt.Interpreter = 'tex';
                    Opt.WindowStyle = 'modal';
                    
                    waitfor(msgbox("\fontsize{14} Maximum Number of Project per Professor Violated. See table for more information and allot again ",...
                        'Error','error',Opt));
                    
                    
                    msg = 'NotNominal';
                    break
                    
                    
                elseif No_of_repetations > 1 || actual_roll_nos ~= unique_roll_nos
                    Opt.Interpreter = 'tex';
                    Opt.WindowStyle = 'modal';
                    
                    waitfor(msgbox("\fontsize{14} There is an clash in the assigned projects or roll numbers. Please try again. ",...
                        'Error','error',Opt));
                    msg = 'NotNominal';
                    break
                else
                    msg = 'EverythingNominal';
                end
                
            end
            
            if strcmpi(msg,'NotNominal')  == 0
                %msg{i,1} = 'EverythingNominal';
                setappdata(0,"Roll_nos_GUI",roll_nos_final)
                setappdata(0,"Projnames_GUI",projnames_gui_final)
                % assignin('base','Roll_nos_GUI',roll_nos_final) % Export a variable back to workspace
                % assignin('base','Projnames_GUI',projnames_gui_final)
                %save guioutput
                closereq
            end
            
            
        end

        % Image clicked function: Image
        function ImageClicked(app, event)
            
        end

        % Button pushed function: NoparticularchoicesButton
        function NoparticularchoicesButtonPushed(app, event)
            roll_nos_final(1:5,:) = {'Select a roll number'};
            projnames_gui_final(1:5,:) = {'Select a project'};
            %setappdata(0,'Roll_nos_GUI',roll_nos_final)
            %setappdata(0,'Projnames_GUI',projnames_gui_final)
            assignin('base','Roll_nos_GUI',roll_nos_final) % Export a variable back to workspace
            assignin('base','Projnames_GUI',projnames_gui_final)
            close(uifigure);
            closereq
        end

        % Value changed function: StudentInfoDropDown
        function StudentInfoDropDownValueChanged(app, event)
            value = app.StudentInfoDropDown.Value;
            
            if strcmpi(app.StudentInfoDropDown.Value,'Select a roll no') == 0
                app.Lamp.Color = 'g';
                par_index = find(strcmpi(string(app.RN),string(value)));
                app.ProjectInfoDropDown.Items = app.PR(par_index,1:4);
                app.StudentInfoDropDown_2.Enable = 'on';
                app.ProjectInfoDropDown_2.Enable='on';
            end
            
            clear par_index
        end

        % Value changed function: StudentInfoDropDown_2
        function StudentInfoDropDown_2ValueChanged(app, event)
            value = app.StudentInfoDropDown_2.Value;
            
            if strcmpi(app.StudentInfoDropDown_2.Value,'Select a roll no') == 0
                app.Lamp_2.Color = 'g';
                par_index = find(strcmpi(string(app.RN),string(value)));
                app.ProjectInfoDropDown_2.Items = app.PR(par_index,1:4);
                app.StudentInfoDropDown_3.Enable = 'on';
                app.ProjectInfoDropDown_3.Enable='on';
            end
            clear par_index
        end

        % Value changed function: StudentInfoDropDown_3
        function StudentInfoDropDown_3ValueChanged(app, event)
            
            value = app.StudentInfoDropDown_3.Value;
            if strcmpi(app.StudentInfoDropDown_3.Value,'Select a roll no') == 0
                app.Lamp_3.Color = 'g';
                par_index = find(strcmpi(string(app.RN),string(value)));
                app.ProjectInfoDropDown_3.Items = app.PR(par_index,1:4);
                app.StudentInfoDropDown_4.Enable = 'on';
            app.ProjectInfoDropDown_4.Enable='on';
            end
            clear par_index
        end

        % Value changed function: StudentInfoDropDown_4
        function StudentInfoDropDown_4ValueChanged(app, event)
            value = app.StudentInfoDropDown_4.Value;
            
            if strcmpi(app.StudentInfoDropDown_4.Value,'Select a roll no') == 0
                app.Lamp_4.Color = 'g';
                par_index = find(strcmpi(string(app.RN),string(value)));
                app.ProjectInfoDropDown_4.Items = app.PR(par_index,1:4);
                app.StudentInfoDropDown_5.Enable = 'on';
            app.ProjectInfoDropDown_5.Enable='on';
            end
            clear par_index
        end

        % Value changed function: StudentInfoDropDown_5
        function StudentInfoDropDown_5ValueChanged(app, event)
            value = app.StudentInfoDropDown_5.Value;
            if strcmpi(app.StudentInfoDropDown_5.Value,'Select a roll no') == 0
                app.Lamp_5.Color = 'g';
                par_index = find(strcmpi(string(app.RN),string(value)));
                app.ProjectInfoDropDown_5.Items = app.PR(par_index,1:4);
                app.StudentInfoDropDown_6.Enable = 'on';
            app.ProjectInfoDropDown_6.Enable='on';
            end
            clear par_index
        end

        % Value changed function: ProjectInfoDropDown
        function ProjectInfoDropDownValueChanged(app, event)
            value = app.ProjectInfoDropDown.Value;
            
            if strcmpi(app.ProjectInfoDropDown.Value,'Select a project') == 0
                app.Lamp.Color = 'g';
                
            end
            
        end

        % Value changed function: ProjectInfoDropDown_2
        function ProjectInfoDropDown_2ValueChanged(app, event)
            value = app.ProjectInfoDropDown_2.Value;
            
            if strcmpi(app.ProjectInfoDropDown_2.Value,'Select a project') == 0
                app.Lamp_2.Color = 'g';
                
            end
            
        end

        % Value changed function: ProjectInfoDropDown_3
        function ProjectInfoDropDown_3ValueChanged(app, event)
            value = app.ProjectInfoDropDown_3.Value;
            if strcmpi(app.ProjectInfoDropDown_3.Value,'Select a project') == 0
                app.Lamp_3.Color = 'g';
                
            end
            
        end

        % Value changed function: ProjectInfoDropDown_4
        function ProjectInfoDropDown_4ValueChanged(app, event)
            value = app.ProjectInfoDropDown_4.Value;
            if strcmpi(app.ProjectInfoDropDown_4.Value,'Select a project') == 0
                app.Lamp_4.Color = 'g';
            end
            
        end

        % Value changed function: ProjectInfoDropDown_5
        function ProjectInfoDropDown_5ValueChanged(app, event)
            value = app.ProjectInfoDropDown_5.Value;
            if strcmpi(app.ProjectInfoDropDown_5.Value,'Select a project') == 0
                app.Lamp_5.Color = 'g';
            end
        end

        % Close request function: UIFigure
        function UIFigureCloseRequest(app, event)
            %uiresume(app.UIFigure)
            msg='Closing this window will stop the allocation completely. Do you still want to proceed?';
            title = 'confirm';
            out = uiconfirm(app.UIFigure,msg,title,'Options',{'Yes','No'},'DefaultOption',1,"CancelOption",2,...
                'Icon','warning');
            if strcmpi(out,'Yes') == 1
                delete(app)
                error('Allocation stopped. Please try again')
                
            else
                
            end
        end

        % Value changed function: StudentInfoDropDown_6
        function StudentInfoDropDown_6ValueChanged(app, event)
            value = app.StudentInfoDropDown_6.Value;
            
            if strcmpi(app.StudentInfoDropDown_6.Value,'Select a roll no') == 0
                app.Lamp_6.Color = 'g';
                par_index = find(strcmpi(string(app.RN),string(value)));
                app.ProjectInfoDropDown_6.Items = app.PR(par_index,1:4);
                app.StudentInfoDropDown_7.Enable = 'on';
            app.ProjectInfoDropDown_7.Enable='on';
            end
            clear par_index
            
        end

        % Value changed function: StudentInfoDropDown_7
        function StudentInfoDropDown_7ValueChanged(app, event)
            value = app.StudentInfoDropDown_7.Value;
            if strcmpi(app.StudentInfoDropDown_7.Value,'Select a roll no') == 0
                app.Lamp_7.Color = 'g';
                par_index = find(strcmpi(string(app.RN),string(value)));
                app.ProjectInfoDropDown_7.Items = app.PR(par_index,1:4);
                app.StudentInfoDropDown_8.Enable = 'on';
                app.ProjectInfoDropDown_8.Enable='on';
            end
            clear par_index
        end

        % Value changed function: StudentInfoDropDown_8
        function StudentInfoDropDown_8ValueChanged(app, event)
            value = app.StudentInfoDropDown_8.Value;
            if strcmpi(app.StudentInfoDropDown_8.Value,'Select a roll no') == 0
                app.Lamp_8.Color = 'g';
                par_index = find(strcmpi(string(app.RN),string(value)));
                app.ProjectInfoDropDown_8.Items = app.PR(par_index,1:4);
                app.StudentInfoDropDown_9.Enable = 'on';
                app.ProjectInfoDropDown_9.Enable='on';
            end
            clear par_index
        end

        % Value changed function: StudentInfoDropDown_9
        function StudentInfoDropDown_9ValueChanged(app, event)
            value = app.StudentInfoDropDown_9.Value;
            if strcmpi(app.StudentInfoDropDown_9.Value,'Select a roll no') == 0
                app.Lamp_9.Color = 'g';
                par_index = find(strcmpi(string(app.RN),string(value)));
                app.ProjectInfoDropDown_9.Items = app.PR(par_index,1:4);
                app.StudentInfoDropDown_10.Enable = 'on';
            app.ProjectInfoDropDown_10.Enable='on';
            end
            clear par_index
        end

        % Value changed function: StudentInfoDropDown_10
        function StudentInfoDropDown_10ValueChanged(app, event)
            value = app.StudentInfoDropDown_10.Value;
            if strcmpi(app.StudentInfoDropDown_10.Value,'Select a roll no') == 0
                app.Lamp_10.Color = 'g';
                par_index = find(strcmpi(string(app.RN),string(value)));
                app.ProjectInfoDropDown_10.Items = app.PR(par_index,1:4);
            end
            clear par_index
        end

        % Value changed function: ProjectInfoDropDown_6
        function ProjectInfoDropDown_6ValueChanged(app, event)
            value = app.ProjectInfoDropDown_6.Value;
            if strcmpi(app.ProjectInfoDropDown_6.Value,'Select a project') == 0
                app.Lamp_6.Color = 'g';
            end
        end

        % Value changed function: ProjectInfoDropDown_7
        function ProjectInfoDropDown_7ValueChanged(app, event)
            value = app.ProjectInfoDropDown_7.Value;
            if strcmpi(app.ProjectInfoDropDown_7.Value,'Select a project') == 0
                app.Lamp_7.Color = 'g';
            end
        end

        % Value changed function: ProjectInfoDropDown_8
        function ProjectInfoDropDown_8ValueChanged(app, event)
            value = app.ProjectInfoDropDown_8.Value;
            if strcmpi(app.ProjectInfoDropDown_8.Value,'Select a project') == 0
                app.Lamp_8.Color = 'g';
            end
        end

        % Value changed function: ProjectInfoDropDown_9
        function ProjectInfoDropDown_9ValueChanged(app, event)
            value = app.ProjectInfoDropDown_9.Value;
            if strcmpi(app.ProjectInfoDropDown_9.Value,'Select a project') == 0
                app.Lamp_9.Color = 'g';
            end
        end

        % Value changed function: ProjectInfoDropDown_10
        function ProjectInfoDropDown_10ValueChanged(app, event)
            value = app.ProjectInfoDropDown_10.Value;
            if strcmpi(app.ProjectInfoDropDown_10.Value,'Select a project') == 0
                app.Lamp_10.Color = 'g';
            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIFigure.CloseRequestFcn = createCallbackFcn(app, @UIFigureCloseRequest, true);
            app.LoadDataButton = uibutton(app.UIFigure, 'push');
            app.LoadDataButton.ButtonPushedFcn = createCallbackFcn(app, @LoadDataButtonPushed, true);
            app.LoadDataButton.Icon = '252_Double_Play_next_smooth_outline.gif';
            app.LoadDataButton.Tooltip = {'Load Data'; ''};
            app.LoadDataButton.Text = 'Load Data';
            app.ParticularChoicesLabel = uilabel(app.UIFigure);
            app.ParticularChoicesLabel.Text = 'Particular Choices';
            app.StudentInfoDropDownLabel = uilabel(app.UIFigure);
            app.StudentInfoDropDownLabel.Text = 'Student Info';
            app.StudentInfoDropDown = uidropdown(app.UIFigure);
            app.StudentInfoDropDown.Items = {'Roll number'};
            app.StudentInfoDropDown.ValueChangedFcn = createCallbackFcn(app, @StudentInfoDropDownValueChanged, true);
            app.StudentInfoDropDown.Value = 'Roll number';
            app.ProjectInfoDropDownLabel = uilabel(app.UIFigure);
            app.ProjectInfoDropDownLabel.Text = 'Project Info';
            app.ProjectInfoDropDown = uidropdown(app.UIFigure);
            app.ProjectInfoDropDown.Items = {'project name'};
            app.ProjectInfoDropDown.ValueChangedFcn = createCallbackFcn(app, @ProjectInfoDropDownValueChanged, true);
            app.ProjectInfoDropDown.Value = 'project name';
            app.DoneButton = uibutton(app.UIFigure, 'push');
            app.DoneButton.ButtonPushedFcn = createCallbackFcn(app, @DoneButtonPushed, true);
            app.DoneButton.Text = 'Done';
            app.StudentInfoDropDown_2Label = uilabel(app.UIFigure);
            app.StudentInfoDropDown_2Label.Enable = 'off';
            app.StudentInfoDropDown_2Label.Text = 'Student Info';
            app.StudentInfoDropDown_2 = uidropdown(app.UIFigure);
            app.StudentInfoDropDown_2.Items = {'Roll number'};
            app.StudentInfoDropDown_2.ValueChangedFcn = createCallbackFcn(app, @StudentInfoDropDown_2ValueChanged, true);
            app.StudentInfoDropDown_2.Enable = 'off';
            app.StudentInfoDropDown_2.Value = 'Roll number';
            app.ProjectInfoDropDown_2Label = uilabel(app.UIFigure);
            app.ProjectInfoDropDown_2Label.Enable = 'off';
            app.ProjectInfoDropDown_2Label.Text = 'Project Info';
            app.ProjectInfoDropDown_2 = uidropdown(app.UIFigure);
            app.ProjectInfoDropDown_2.Items = {'project name'};
            app.ProjectInfoDropDown_2.ValueChangedFcn = createCallbackFcn(app, @ProjectInfoDropDown_2ValueChanged, true);
            app.ProjectInfoDropDown_2.Enable = 'off';
            app.ProjectInfoDropDown_2.Value = 'project name';
            app.StudentInfoDropDown_3Label = uilabel(app.UIFigure);
            app.StudentInfoDropDown_3Label.Enable = 'off';
            app.StudentInfoDropDown_3Label.Text = 'Student Info';
            app.StudentInfoDropDown_3 = uidropdown(app.UIFigure);
            app.StudentInfoDropDown_3.Items = {'Roll number'};
            app.StudentInfoDropDown_3.ValueChangedFcn = createCallbackFcn(app, @StudentInfoDropDown_3ValueChanged, true);
            app.StudentInfoDropDown_3.Enable = 'off';
            app.StudentInfoDropDown_3.Value = 'Roll number';
            app.ProjectInfoDropDown_3Label = uilabel(app.UIFigure);
            app.ProjectInfoDropDown_3Label.Enable = 'off';
            app.ProjectInfoDropDown_3Label.Text = 'Project Info';
            app.ProjectInfoDropDown_3 = uidropdown(app.UIFigure);
            app.ProjectInfoDropDown_3.Items = {'project name'};
            app.ProjectInfoDropDown_3.ValueChangedFcn = createCallbackFcn(app, @ProjectInfoDropDown_3ValueChanged, true);
            app.ProjectInfoDropDown_3.Enable = 'off';
            app.ProjectInfoDropDown_3.Value = 'project name';
            app.StudentInfoDropDown_4Label = uilabel(app.UIFigure);
            app.StudentInfoDropDown_4Label.Enable = 'off';
            app.StudentInfoDropDown_4Label.Text = 'Student Info';
            app.StudentInfoDropDown_4 = uidropdown(app.UIFigure);
            app.StudentInfoDropDown_4.Items = {'Roll number'};
            app.StudentInfoDropDown_4.ValueChangedFcn = createCallbackFcn(app, @StudentInfoDropDown_4ValueChanged, true);
            app.StudentInfoDropDown_4.Enable = 'off';
            app.StudentInfoDropDown_4.Value = 'Roll number';
            app.ProjectInfoDropDown_4Label = uilabel(app.UIFigure);
            app.ProjectInfoDropDown_4Label.Enable = 'off';
            app.ProjectInfoDropDown_4Label.Text = 'Project Info';
            app.ProjectInfoDropDown_4 = uidropdown(app.UIFigure);
            app.ProjectInfoDropDown_4.Items = {'project name'};
            app.ProjectInfoDropDown_4.ValueChangedFcn = createCallbackFcn(app, @ProjectInfoDropDown_4ValueChanged, true);
            app.ProjectInfoDropDown_4.Enable = 'off';
            app.ProjectInfoDropDown_4.Value = 'project name';
            app.StudentInfoDropDown_5Label = uilabel(app.UIFigure);
            app.StudentInfoDropDown_5Label.Enable = 'off';
            app.StudentInfoDropDown_5Label.Text = 'Student Info';
            app.StudentInfoDropDown_5 = uidropdown(app.UIFigure);
            app.StudentInfoDropDown_5.Items = {'Roll number'};
            app.StudentInfoDropDown_5.ValueChangedFcn = createCallbackFcn(app, @StudentInfoDropDown_5ValueChanged, true);
            app.StudentInfoDropDown_5.Enable = 'off';
            app.StudentInfoDropDown_5.Value = 'Roll number';
            app.ProjectInfoDropDown_5Label = uilabel(app.UIFigure);
            app.ProjectInfoDropDown_5Label.Enable = 'off';
            app.ProjectInfoDropDown_5Label.Text = 'Project Info';
            app.ProjectInfoDropDown_5 = uidropdown(app.UIFigure);
            app.ProjectInfoDropDown_5.Items = {'project name'};
            app.ProjectInfoDropDown_5.ValueChangedFcn = createCallbackFcn(app, @ProjectInfoDropDown_5ValueChanged, true);
            app.ProjectInfoDropDown_5.Enable = 'off';
            app.ProjectInfoDropDown_5.Value = 'project name';
            app.Image = uiimage(app.UIFigure);
            app.Image.ImageClickedFcn = createCallbackFcn(app, @ImageClicked, true);
            app.Image.ImageSource = 'iitg.png';
            app.NoparticularchoicesButton = uibutton(app.UIFigure, 'push');
            app.NoparticularchoicesButton.ButtonPushedFcn = createCallbackFcn(app, @NoparticularchoicesButtonPushed, true);
            app.NoparticularchoicesButton.Text = 'No particular choices';
            app.Lamp = uilamp(app.UIFigure);
            app.Lamp_2 = uilamp(app.UIFigure);
            app.Lamp_2.Enable = 'off';
            app.Lamp_3 = uilamp(app.UIFigure);
            app.Lamp_3.Enable = 'off';
            app.Lamp_4 = uilamp(app.UIFigure);
            app.Lamp_4.Enable = 'off';
            app.Lamp_5 = uilamp(app.UIFigure);
            app.Lamp_5.Enable = 'off';
            app.Lamp_6 = uilamp(app.UIFigure);
            app.Lamp_6.Enable = 'off';
            app.StudentInfoDropDown_6Label = uilabel(app.UIFigure);
            app.StudentInfoDropDown_6Label.Enable = 'off';
            app.StudentInfoDropDown_6Label.Text = 'Student Info';
            app.StudentInfoDropDown_6 = uidropdown(app.UIFigure);
            app.StudentInfoDropDown_6.Items = {'Roll number'};
            app.StudentInfoDropDown_6.ValueChangedFcn = createCallbackFcn(app, @StudentInfoDropDown_6ValueChanged, true);
            app.StudentInfoDropDown_6.Enable = 'off';
            app.StudentInfoDropDown_6.Value = 'Roll number';
            app.ProjectInfoDropDown_6Label = uilabel(app.UIFigure);
            app.ProjectInfoDropDown_6Label.Enable = 'off';
            app.ProjectInfoDropDown_6Label.Text = 'Project Info';
            app.ProjectInfoDropDown_6 = uidropdown(app.UIFigure);
            app.ProjectInfoDropDown_6.Items = {'project name'};
            app.ProjectInfoDropDown_6.ValueChangedFcn = createCallbackFcn(app, @ProjectInfoDropDown_6ValueChanged, true);
            app.ProjectInfoDropDown_6.Enable = 'off';
            app.ProjectInfoDropDown_6.Value = 'project name';
            app.Lamp_7 = uilamp(app.UIFigure);
            app.Lamp_7.Enable = 'off';
            app.StudentInfoDropDown_7Label = uilabel(app.UIFigure);
            app.StudentInfoDropDown_7Label.Enable = 'off';
            app.StudentInfoDropDown_7Label.Text = 'Student Info';
            app.StudentInfoDropDown_7 = uidropdown(app.UIFigure);
            app.StudentInfoDropDown_7.Items = {'Roll number'};
            app.StudentInfoDropDown_7.ValueChangedFcn = createCallbackFcn(app, @StudentInfoDropDown_7ValueChanged, true);
            app.StudentInfoDropDown_7.Enable = 'off';
            app.StudentInfoDropDown_7.Value = 'Roll number';
            app.ProjectInfoDropDown_7Label = uilabel(app.UIFigure);
            app.ProjectInfoDropDown_7Label.Enable = 'off';
            app.ProjectInfoDropDown_7Label.Text = 'Project Info';
            app.ProjectInfoDropDown_7 = uidropdown(app.UIFigure);
            app.ProjectInfoDropDown_7.Items = {'project name'};
            app.ProjectInfoDropDown_7.ValueChangedFcn = createCallbackFcn(app, @ProjectInfoDropDown_7ValueChanged, true);
            app.ProjectInfoDropDown_7.Enable = 'off';
            app.ProjectInfoDropDown_7.Value = 'project name';
            app.Lamp_8 = uilamp(app.UIFigure);
            app.Lamp_8.Enable = 'off';
            app.StudentInfoDropDown_8Label = uilabel(app.UIFigure);
            app.StudentInfoDropDown_8Label.Enable = 'off';
            app.StudentInfoDropDown_8Label.Text = 'Student Info';
            app.StudentInfoDropDown_8 = uidropdown(app.UIFigure);
            app.StudentInfoDropDown_8.Items = {'Roll number'};
            app.StudentInfoDropDown_8.ValueChangedFcn = createCallbackFcn(app, @StudentInfoDropDown_8ValueChanged, true);
            app.StudentInfoDropDown_8.Enable = 'off';
            app.StudentInfoDropDown_8.Value = 'Roll number';
            app.ProjectInfoDropDown_8Label = uilabel(app.UIFigure);
            app.ProjectInfoDropDown_8Label.Enable = 'off';
            app.ProjectInfoDropDown_8Label.Text = 'Project Info';
            app.ProjectInfoDropDown_8 = uidropdown(app.UIFigure);
            app.ProjectInfoDropDown_8.Items = {'project name'};
            app.ProjectInfoDropDown_8.ValueChangedFcn = createCallbackFcn(app, @ProjectInfoDropDown_8ValueChanged, true);
            app.ProjectInfoDropDown_8.Enable = 'off';
            app.ProjectInfoDropDown_8.Value = 'project name';
            app.Lamp_9 = uilamp(app.UIFigure);
            app.Lamp_9.Enable = 'off';
            app.StudentInfoDropDown_9Label = uilabel(app.UIFigure);
            app.StudentInfoDropDown_9Label.Enable = 'off';
            app.StudentInfoDropDown_9Label.Text = 'Student Info';
            app.StudentInfoDropDown_9 = uidropdown(app.UIFigure);
            app.StudentInfoDropDown_9.Items = {'Roll number'};
            app.StudentInfoDropDown_9.ValueChangedFcn = createCallbackFcn(app, @StudentInfoDropDown_9ValueChanged, true);
            app.StudentInfoDropDown_9.Enable = 'off';
            app.StudentInfoDropDown_9.Value = 'Roll number';
            app.ProjectInfoDropDown_9Label = uilabel(app.UIFigure);
            app.ProjectInfoDropDown_9Label.Enable = 'off';
            app.ProjectInfoDropDown_9Label.Text = 'Project Info';
            app.ProjectInfoDropDown_9 = uidropdown(app.UIFigure);
            app.ProjectInfoDropDown_9.Items = {'project name'};
            app.ProjectInfoDropDown_9.ValueChangedFcn = createCallbackFcn(app, @ProjectInfoDropDown_9ValueChanged, true);
            app.ProjectInfoDropDown_9.Enable = 'off';
            app.ProjectInfoDropDown_9.Value = 'project name';
            app.Lamp_10 = uilamp(app.UIFigure);
            app.Lamp_10.Enable = 'off';
            app.StudentInfoDropDown_10Label = uilabel(app.UIFigure);
            app.StudentInfoDropDown_10Label.Enable = 'off';
            app.StudentInfoDropDown_10Label.Text = 'Student Info';
            app.StudentInfoDropDown_10 = uidropdown(app.UIFigure);
            app.StudentInfoDropDown_10.Items = {'Roll number'};
            app.StudentInfoDropDown_10.ValueChangedFcn = createCallbackFcn(app, @StudentInfoDropDown_10ValueChanged, true);
            app.StudentInfoDropDown_10.Enable = 'off';
            app.StudentInfoDropDown_10.Value = 'Roll number';
            app.ProjectInfoDropDown_10Label = uilabel(app.UIFigure);
            app.ProjectInfoDropDown_10Label.Enable = 'off';
            app.ProjectInfoDropDown_10Label.Text = 'Project Info';
            app.ProjectInfoDropDown_10 = uidropdown(app.UIFigure);
            app.ProjectInfoDropDown_10.Items = {'project name'};
            app.ProjectInfoDropDown_10.ValueChangedFcn = createCallbackFcn(app, @ProjectInfoDropDown_10ValueChanged, true);
            app.ProjectInfoDropDown_10.Enable = 'off';
            app.ProjectInfoDropDown_10.Value = 'project name';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = x_gui

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end